classdef retinaclassification_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cattle_Identification_App  matlab.ui.Figure
        SelectCattleforIdentificationButton  matlab.ui.control.Button
        ImageAxes_11               matlab.ui.control.UIAxes
        ImageAxes_10               matlab.ui.control.UIAxes
        ImageAxes_9                matlab.ui.control.UIAxes
        ImageAxes_8                matlab.ui.control.UIAxes
        ImageAxes_7                matlab.ui.control.UIAxes
        ImageAxes_6                matlab.ui.control.UIAxes
        ImageAxes_5                matlab.ui.control.UIAxes
        ImageAxes_4                matlab.ui.control.UIAxes
        ImageAxes_3                matlab.ui.control.UIAxes
        ImageAxes_2                matlab.ui.control.UIAxes
        ImageAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
    end
    
    methods (Access = public)
        
%          function updateimage(~,~)
%                    
%                              
%                     
%          end
         
                end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
%              imshow(storeK(1).colour_FinalResult);
            
        end

        % Button pushed function: 
        % SelectCattleforIdentificationButton
        function SelectCattleforIdentificationButtonPushed(app, event)
       
    load ('templateTEST.mat');
    tic
            global image1;
            [filename, pathname]=uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'});
            fullpathname = strcat(pathname, filename);
            image1=imread(fullpathname);
              imshow(image1,[], 'Parent',app.ImageAxes);
           
    Resized_Image = imresize(image1, 0.4, 'Antialiasing', true);
    imshow(Resized_Image,[], 'Parent',app.ImageAxes_2);
    % Convert to LAB color space
    Gray_Image = rgb2gray(Resized_Image);
    imshow(Gray_Image,[], 'Parent',app.ImageAxes_3);
    
    % Sharpen image
    griGoruntu_sharpened = imsharpen(Gray_Image, 'Radius', 9, 'Amount', 9);
    imshow(griGoruntu_sharpened,[], 'Parent',app.ImageAxes_3);
    
    % Adjust image
    griGoruntu4 = imadjust(griGoruntu_sharpened, [0 0.87]);
    imshow(griGoruntu4,[], 'Parent',app.ImageAxes_4);
     
    % Apply Wiener filter
    griGoruntu_wiener = wiener2(griGoruntu4, [3 3]);
    imshow(griGoruntu_wiener,[], 'Parent',app.ImageAxes_5);
    
    % Histogram equalization
    Enhanced_Image = adapthisteq(Gray_Image, 'numTiles', [8 8], 'nBins', 64);
    imshow(Enhanced_Image,[], 'Parent',app.ImageAxes_6);
    % Filtering
    Avg_Filter = fspecial('disk',15);
    Filtered_Image = imfilter(Enhanced_Image, Avg_Filter);
    imshow(Filtered_Image,[], 'Parent',app.ImageAxes_7);
    % Thresholding
    Substracted_Image = imsubtract(Filtered_Image, Enhanced_Image);
    level = Threshold_Level(Substracted_Image);
    Binary_Image = imbinarize(Substracted_Image, level-0.006);
    imshow(Binary_Image,[], 'Parent',app.ImageAxes_8);

    % Morphological operations
    Clean_Image = bwareaopen(Binary_Image, 150);
    imshow(Clean_Image,[], 'Parent',app.ImageAxes_9);
    
    Complemented_Image = imcomplement(Clean_Image);
    imshow(Complemented_Image,[], 'Parent',app.ImageAxes_10);
    % Colorize segmented image
   
    Final_Result = Colorize_Image(Resized_Image, Complemented_Image, [0 0 0]);
    imshow(Final_Result,[], 'Parent',app.ImageAxes_11);

    [~, sampleSize]=size(storeK);
%     similarity=zeros(1,sampleSize);
    matchingPercent = zeros(1,sampleSize);

    for i=1:sampleSize
    I1=(storeK(i).gray_FinalResult);
    points1 = detectSURFFeatures(I1);
    [sizeIndexPoints1, ~] = size(points1);

       
            I2=rgb2gray(Final_Result);
            points2 = detectSURFFeatures(I2);
            [sizeIndexPoints2, ~] = size(points2);
            % Extract the features.
            [f1,~] = extractFeatures(I1,points1, 'FeatureSize', 64);
            [f2,~] = extractFeatures(I2,points2,'FeatureSize', 64);
            % Retrieve the locations of matched points.
            indexPairs = matchFeatures(f1,f2, 'Method','Approximate', 'MaxRatio', 0.5) ;
            [sizeIndex,~]=size(indexPairs);
%             similarity(1,i) = sizeIndex;
            matchingPercent(1,i)=(sqrt((sizeIndex*sizeIndex)/(sizeIndexPoints1*sizeIndexPoints2)))*100;

    end
    

 [~, ind1] = max(matchingPercent(1,:));
%%for i = 1:sampleSize
 if (string(storeK(ind1).dosyaadiBolunmus{1, 8})== "_R-") 
  
       if (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "01") 
           irk="Simental";
       elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "02") 
           irk="Simental Hybrid";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "03") 
           irk="Montofon";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "04") 
           irk="Native Head";
             elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "05") 
           irk="Zavot";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "06") 
           irk="Holstein";
       end

         if (string(storeK(ind1).dosyaadiBolunmus{1, 4})== "1") 
             gender="Female";
         elseif (string(storeK(ind1).dosyaadiBolunmus{1, 4})== "2") 
             gender="Male";
         end
             RL="Retina of the Right Eye"; 
prompt =({"Cattle Number = " + storeK(ind1).dosyaadiBolunmus{1, 2} ; "Breed of Cattle = " + irk;  "Gender = " + gender;  "Age = " + storeK(ind1).dosyaadiBolunmus{1, 5} + " months old." ; "Left / Right  = " + RL });
    
h = msgbox(prompt, 'Matcing Cattle');
set(h, 'position', [110 200 200 110]);
ah = get(h, 'CurrentAxes');
ch = get(ah, 'Children');
set(ch, 'FontSize', 12);  
 end
if (string(storeK(ind1).dosyaadiBolunmus{1, 8})== "_L-") 
 
       if (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "01") 
           irk="Simental";
       elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "02") 
           irk="Simental Hybrid";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "03") 
           irk="Montofon";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "04") 
           irk="Native Head";
             elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "05") 
           irk="Zavot";
            elseif (string(storeK(ind1).dosyaadiBolunmus{1, 3})== "06") 
           irk="Holstein";
       end

         if (string(storeK(ind1).dosyaadiBolunmus{1, 4})== "1") 
             gender="Female";
         elseif (string(storeK(ind1).dosyaadiBolunmus{1, 4})== "2") 
             gender="Male";
         end        
             RL="Retina of the Left Eye";     
prompt =({"Cattle Number = " + storeK(ind1).dosyaadiBolunmus{1, 2} ; "Breed of Cattle = " + irk;  "Gender = " + gender;  "Age = " + storeK(ind1).dosyaadiBolunmus{1, 5} + " months old." ; "Left / Right  = " + RL });  
h = msgbox(prompt, 'Matcing Cattle');
set(h, 'position', [110 200 200 110]);
ah = get(h, 'CurrentAxes');
ch = get(ah, 'Children');
set(ch, 'FontSize', 12); 
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cattle_Identification_App = uifigure('Visible', 'off');
            app.Cattle_Identification_App.Name = 'CattNIS: Cattle Identification App';
            app.Cattle_Identification_App.Icon = 'logoinek.png';
            app.ImageAxes = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes, 'Original Image')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes_2 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_2, 'Resized Image')
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.ImageAxes_3 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_3, 'Sharpened Image')
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.YTick = [];
            app.ImageAxes_4 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_4, 'Intensity Adjusted Image')
            app.ImageAxes_4.XTick = [];
            app.ImageAxes_4.YTick = [];
            app.ImageAxes_5 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_5, 'Wiener Filtered Image')
            app.ImageAxes_5.XTick = [];
            app.ImageAxes_5.YTick = [];
            app.ImageAxes_6 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_6, 'Histogram Equalized Image')
            app.ImageAxes_6.XTick = [];
            app.ImageAxes_6.YTick = [];
            app.ImageAxes_7 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_7, 'Average Filtered Image')
            app.ImageAxes_7.XTick = [];
            app.ImageAxes_7.YTick = [];
            app.ImageAxes_8 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_8, 'Binary Image')
            app.ImageAxes_8.XTick = [];
            app.ImageAxes_8.YTick = [];
            app.ImageAxes_9 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_9, 'Small Objects Removed Image')
            app.ImageAxes_9.XTick = [];
            app.ImageAxes_9.YTick = [];
            app.ImageAxes_10 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_10, 'Complemented Image')
            app.ImageAxes_10.XTick = [];
            app.ImageAxes_10.YTick = [];
            app.ImageAxes_11 = uiaxes(app.Cattle_Identification_App);
            title(app.ImageAxes_11, 'Final Coloured Image')
            app.ImageAxes_11.XTick = [];
            app.ImageAxes_11.YTick = [];
            app.SelectCattleforIdentificationButton = uibutton(app.Cattle_Identification_App, 'push');
            app.SelectCattleforIdentificationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCattleforIdentificationButtonPushed, true);
            app.SelectCattleforIdentificationButton.Tag = 'loadedData';
            app.SelectCattleforIdentificationButton.Icon = 'logoinek.png';
            app.SelectCattleforIdentificationButton.Text = {'Select Cattle '; 'for Identification'};
            % Show the figure after all components are created
            app.Cattle_Identification_App.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retinaclassification_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cattle_Identification_App)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cattle_Identification_App)
        end
    end
end